classdef CameraApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LogEdit                      matlab.ui.control.TextArea
        LogTextAreaLabel             matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        ConfigurationTab             matlab.ui.container.Tab
        CameraPanel                  matlab.ui.container.Panel
        closeCameraButton            matlab.ui.control.Button
        ConnectCameraButton          matlab.ui.control.Button
        ThorlabMotorPanel            matlab.ui.container.Panel
        MotorAddressDropDown         matlab.ui.control.DropDown
        MotorAddressDropDownLabel    matlab.ui.control.Label
        ConnectStageButton           matlab.ui.control.Button
        CloseStageButton             matlab.ui.control.Button
        LockStageButton              matlab.ui.control.Button
        CameraTab                    matlab.ui.container.Tab
        CameraSettingPanel           matlab.ui.container.Panel
        AutoExposureButton           matlab.ui.control.Button
        ExposuretimeButton           matlab.ui.control.Button
        ZoomButton                   matlab.ui.control.Button
        LiveButton                   matlab.ui.control.Button
        D4sCheckBox                  matlab.ui.control.CheckBox
        ColormapLabel                matlab.ui.control.Label
        ColormapDropDown             matlab.ui.control.DropDown
        ExposureEditField            matlab.ui.control.NumericEditField
        msEditFieldLabel             matlab.ui.control.Label
        ZoomEditField                matlab.ui.control.NumericEditField
        M2Tab                        matlab.ui.container.Tab
        CalculateM2Button            matlab.ui.control.Button
        ZrEditField                  matlab.ui.control.NumericEditField
        ZrEditFieldLabel             matlab.ui.control.Label
        FindRayleighLengthButton     matlab.ui.control.Button
        SteplengthEditField          matlab.ui.control.NumericEditField
        SteplengthEditFieldLabel     matlab.ui.control.Label
        MinimalStepEditField         matlab.ui.control.NumericEditField
        MinimalStepmmEditFieldLabel  matlab.ui.control.Label
        Z0EditField                  matlab.ui.control.NumericEditField
        Z0EditFieldLabel             matlab.ui.control.Label
        W0EditField                  matlab.ui.control.NumericEditField
        W0EditFieldLabel             matlab.ui.control.Label
        FindWaistLocationButton      matlab.ui.control.Button
        InitialStepEditField         matlab.ui.control.NumericEditField
        InitialStepmmEditFieldLabel  matlab.ui.control.Label
        ThorlabStagePanel            matlab.ui.container.Panel
        PositionEditField            matlab.ui.control.EditField
        PositionEditField_2Label     matlab.ui.control.Label
        MovetoabsoluteButton         matlab.ui.control.Button
        MovetoPositionEditField      matlab.ui.control.NumericEditField
        JogsizeEditField             matlab.ui.control.NumericEditField
        JogsizeEditFieldLabel        matlab.ui.control.Label
        DownButton                   matlab.ui.control.Button
        UpButton                     matlab.ui.control.Button
    end

    
    properties (Access = public)
        Stage % Thorlab Stage object
        cam % camera object
        src
        cam_param
        cam_live_view
        MotorPool


    end

    methods (Access = public)


        function UpdateLog(app,varargin)
                % THIS FUNCTION UPDATES THE LOGBOX (ADDS A NEW LINE WITH THE NEW STRING)
                % varargin = either ("Message","Color") or ("Message") $
                switch nargin
                    case 2
                        app.LogEdit.Value{end+1} = char(append(string(datetime),': ',convertCharsToStrings(varargin{1})));
                    case 3
                        app.LogEdit.Value{end+1} = char(append(string(datetime),': ',convertCharsToStrings(varargin{1})));
                        app.LogEdit.BackgroundColor = varargin{2};
                end
                drawnow;
        end


         function UpdateStagePosition(app)
            [Pos,ErrMsg]= app.Stage.GetPosition();
            if strcmp(ErrMsg.color,'red')
                app.UpdateLog(ErrMsg.txt,ErrMsg.color);
            else
                app.PositionEditField.Value = [num2str(round(Pos(1),4))];
                drawnow;
            end
         end


         function [A, B, C, x_new, y_new] = quadraticFit(app, X, Y, numPoints)
             % Inputs:
             % X - vector of x values
             % Y - vector of y values
             % numPoints - number of points for the fit (optional, default = 100)

             % Ensure inputs are column vectors
             X = X(:);
             Y = Y(:);

             % Perform the quadratic fit using the fit function
             fitModel = fit(X, Y, 'poly2');

             % Extract coefficients from the fit object
             % 'poly2' fits the model: y = p1*x^2 + p2*x + p3
             C = fitModel.p1; % Coefficient for x^2
             B = fitModel.p2; % Coefficient for x
             A = fitModel.p3; % Constant term

             % Create new x values for the fit (default 100 points if not specified)
             if nargin < 3
                 numPoints = 100;
             end
             x_new = linspace(min(X), max(X), numPoints);

             % Compute the fitted y values
             y_new = A + B * x_new + C * x_new.^2;
         end


         function ExpValue = SetAutoExposure(app)

             counter = 1;
             Ncounter = 30;

             % Cconfigurations
             ExpLimits = app.ExposureEditField.Limits;
             app.src.AutoExposureTimeUpperLimit = ExpLimits(2);
             app.src.AutoExposureTimeLowerLimit = ExpLimits(1);
             app.src.AutoFunctionProfile = "MinimizeGain";
             app.src.AutoTargetBrightness = 0.8;

             while counter<=Ncounter

                 app.src.ExposureAuto = "Once";
                 pause(0.05);
                 ExpValueTmp(counter) = app.src.ExposureTime;
                 pause(0.1+ExpValueTmp(counter)/1e6);
                 % frame = app.cam.getFrame(); % TODO: need it?
                 pause(0.05);

                 app.set_exposure(round( ExpValueTmp(counter) ))

                 % MaxVal = max(max(frame));

                 % TODO: check the condition
                 if counter > 1
                     TmpFlag = abs(ExpValueTmp(counter)-ExpValueTmp(counter-1))/ExpValueTmp(counter);
                     if TmpFlag<0.02
                         break
                     end
                 end
                 counter = counter + 1;
             end

             app.src.ExposureAuto = "Off";
             ExpValue = ExpValueTmp(end);

         end


         function ExpValue = SetAutoExposure2(app)

             % run a binary serch on the exposure time until the maximum
             % value is in the deffined range

             upper_range = 245;
             lower_range = 235;

             ExpLimits = app.ExposureEditField.Limits;
             ExposureTimeMax = ExpLimits(2);
             ExposureTimeMin = ExpLimits(1);

             cur_min = ExposureTimeMin;
             cur_max = ExposureTimeMax;

             current_exposure = (cur_max + cur_min) / 2;


             while (current_exposure > ExposureTimeMin) && (current_exposure < ExposureTimeMax)

                 current_exposure = (cur_max + cur_min) / 2;
                 app.set_exposure(current_exposure);
                 frame = app.device.getFrame();

                 max_val = max(max(frame));

                 if max_val > upper_range
                     cur_max = current_exposure;
                 elseif max_val < lower_range
                     cur_min = current_exposure;
                 else
                     break;
                 end

             end

             ExpValue = current_exposure;

         end


         function set_exposure(app, time)

             app.ExposureEditField.Value = time;
             app.ExposuretimeButtonPushed();
             
         end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LockStageButton
        function LockStageButtonPushed(app, event)
            app.UpdateLog('Attempting to initiate communication with Linear stages',[1 1 0]); % yellow
            app.Stage = ThorlabsStageController(app.MotorAddressDropDown.Value,0); % set connection

            if app.Stage.InitStatus


                RetMsg = app.Stage.InitStage(app.UIFigure); % homming stage

                if ~strcmp(RetMsg.color,'red') % no erros

                    app.ThorlabStagePanel.Enable = 'on';
                    app.UpdateLog('Communication initiation with Linear stages succeeded','green');
                    app.MovetoPositionEditField.Limits=[app.Stage.XStageLimits];


                    app.UpdateLog(RetMsg.txt,RetMsg.color);
                    app.CloseStageButton.Enable = 'on';
                    app.LockStageButton.Text = 'Locked';
                    app.LockStageButton.Enable = 'off';
                    app.LockStageButton.BackgroundColor = [0 1 0]; % green

                    if strcmp(app.LockStageButton.Text,'Locked') && strcmp(app.ConnectCameraButton.Text, 'Connected')
                        app.FindWaistLocationButton.Enable = on;
                    end


                else
                    uialert(app.UIFigure ,"Initializing Linear stage controller failed!","ERROR");
                    app.UpdateLog('Communication initiation with Linear stages Failed','red')
                    app.UpdateLog(app.Stage.ErrMsg)


                end
            end
        end

        % Button pushed function: FindWaistLocationButton
        function FindWaistLocationButtonPushed(app, event)
            
            app.UpdateLog('Start finding the waist location')
            d = uiprogressdlg(app.UIFigure,"Title",'Please wait','Message',"Finding Waist Location");


            initial_jog = app.InitialStepEditField.Value;
            limit_jog = app.MinimalStepEditField.Value;
            direction = true; % start by going up


            % setting to plot
            figure;
            hold on;
            title('Finding Waist');
            xlabel('Z [mm]');
            ylabel('D4\sigma');
            % Initialize an empty plot
            h = plot([], [], 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');
            % Initialize empty arrays for x and y coordinates
            x = [];
            y = [];

            % move stage to 0
            msg = app.Stage.MoveToAbsPosition(0);
            app.UpdateLog(msg.txt,msg.color);

            % take image and calculate width
            D4s = app.cam.get_D4sigma(app.cam.getFrame());
            pre_width = D4s.D4sigmaXY;

            % Update the plot data
            x = [x, app.PositionEditField.Value];
            y = [y, pre_width];
            set(h, 'XData', x, 'YData', y);
            drawnow;

            app.JogsizeEditField.Value = initial_jog;

            current_width = pre_width;

            % find the intrest zone
            while pre_width >= current_width
                
                pre_width = current_width;

                % jog up and calculate width
                app.UpButtonPushed;
                D4s = app.cam.get_D4sigma(app.cam.getFrame());
                current_width = D4s.D4sigmaXY;

                % Update the plot data
                x = [x, app.PositionEditField.Value];
                y = [y, current_width];
                set(h, 'XData', x, 'YData', y);
                drawnow;

            end

            % start taking smaller steps
            app.JogsizeEditField.Value = app.JogsizeEditField.Value / 2;

            
            while app.JogsizeEditField.Value >= limit_jog

                if pre_width < current_width % switch side
                    direction = ~direction;
                end

                % jog
                if direction % jog up
                    app.UpButtonPushed;

                else % jog down
                    app.DownButtonPushed;
                end

                pre_width = current_width;
                % calculate width
                D4s = app.cam.get_D4sigma(app.cam.getFrame());
                current_width = D4s.D4sigmaXY;

                % Update the plot data
                x = [x, app.PositionEditField.Value];
                y = [y, current_width];
                set(h, 'XData', x, 'YData', y);
                drawnow;

                % start taking smaller steps
                app.JogsizeEditField.Value = app.JogsizeEditField.Value / 2;
            end


            % Done
            close(d)
            app.UpdateLog('DONE finding the waist location')
            app.W0EditField.Value = current_width;
            app.Z0EditField.Value = app.PositionEditField.Value;

            app.FindWaistLocationButton.Enable = 'off';
            app.FindWaistLocationButton.Text = 'Done';
            app.FindWaistLocationButton.BackgroundColor = [0 1 0]; % green
            app.FindRayleighLengthButton.Enable = 'on';

        end

        % Button pushed function: CloseStageButton
        function CloseStageButtonPushed(app, event)

            app.Stage.MoveToAbsPosition(0); % return to home possition
            RetMsg = app.Stage.CloseComm();
            if strcmp(RetMsg.color,'green') || strcmp(RetMsg.color,'blue')
                RetMsg.color = 'white'; % if shutdown sucessfull = mark it white to let user know "com not open"

                app.LockStageButton.BackgroundColor = [1 0 0]; % red
                app.ConnectStageButton.Enable = "on";
                app.ConnectStageButton.Text = 'Connect';
                app.ConnectStageButton.BackgroundColor = [1 0 0]; % red
            end
            app.UpdateLog(RetMsg.txt,RetMsg.color);
            app.Stage = [];

            
        end

        % Button pushed function: ConnectCameraButton
        function ConnectCameraButtonPushed(app, event)

            app.cam = camera();

            if app.cam.InitStatus

                app.CameraSettingPanel.Enable = "on";
                app.UpdateLog('Camera Connected', [0 1 0]);
                app.ConnectCameraButton.Text = 'Connected';
                app.ConnectCameraButton.Enable = "off";
                app.ConnectCameraButton.BackgroundColor = [0 1 0];

                app.ZoomEditField.Value = min([app.cam.param.Height app.cam.param.Width]);
                app.ZoomEditField.Limits = [8, min([app.cam.param.Height app.cam.param.Width])];

                app.closeCameraButton.Enable = 'on';

                if strcmp(app.LockStageButton.Text,'Locked') && strcmp(app.ConnectCameraButton.Text, 'Connected')
                        app.FindWaistLocationButton.Enable = on;
                end

            else
                app.UpdateLog(app.cam.msg.txt, app.cam.msg.color);
            end


        end

        % Button pushed function: LiveButton
        function LiveButtonPushed(app, event)
            if app.cam.InitStatus

                app.cam_live_view = figure;
                frame = app.cam.getFrame();
                img_ax = axes;

                image = imagesc(img_ax,frame);

                colormap(img_ax, app.ColormapDropDown.Value);
                set(gca, 'CLim', [0 4095]);
                colorbar;
                pbaspect([size(frame,2) size(frame,1) 1]);

                hold on
                elip = plot(1:app.cam.param.Width, 1:app.cam.param.Width,...
                    'LineStyle','none', 'Marker','*', 'MarkerEdgeColor',[1 1 1],...
                    'MarkerSize', 3);
                hold off
                ylim=get(gca,'ylim');
                xlim=get(gca,'xlim');

                if app.D4sCheckBox.Value
                    textData = {'D4\sigmaX: ', 'D4\sigmaY: '};
                else
                    textData = ' ';
                end

                txtH = text(xlim(1)+2, ylim(1)+75, textData, 'FontSize',14,'Color','white');


                while ishandle(app.cam_live_view)

                    frame = app.cam.getFrame();

                    if app.D4sCheckBox.Value
                        D4sigma = app.cam.get_D4sigma(frame);
                        elip.XData = D4sigma.xel;
                        elip.YData = D4sigma.yel;
                        txtH.String = {['D4\sigmaX: ' num2str(round(D4sigma.D4sigmaX))...
                            '(' num2str(round(D4sigma.D4sigmaX/1.699)) ')'],...
                            ['D4\sigmaY: ' num2str(round(D4sigma.D4sigmaY))...
                            '(' num2str(round(D4sigma.D4sigmaY/1.699)) ')']};
                    else
                        elip.XData = [];
                        elip.YData = [];
                        txtH.String = [];
                    end

                    image.CData = frame;

                    drawnow;

                end

                app.cam_live_view = [];

            end
        end

        % Button pushed function: closeCameraButton
        function closeCameraButtonPushed(app, event)
            msg = app.cam.closeConnection();

            if msg.color == [0 1 0] % green
                app.CameraSettingPanel.Enable = "off";
                app.closeCameraButton.Enable = "off";
                app.ConnectCameraButton.Enable = 'on';
                app.ConnectCameraButton.Text = 'Connect';
                app.ConnectCameraButton.BackgroundColor = [1 0 0]; % red
            end
            app.UpdateLog(msg.txt,msg.color);
            app.cam = [];
        end

        % Value changed function: ExposureEditField
        function ExposureEditFieldValueChanged(app, event)

        end

        % Button pushed function: ConnectStageButton
        function ConnectStageButtonPushed(app, event)
            TempObj = tlDCMotor();                  % Temp object to get list of motors
            SerialNumbers = TempObj.GetMotorList(); % get all available serial numbers
            TempArray = startsWith(SerialNumbers , '70') + startsWith(SerialNumbers , '71') + startsWith(SerialNumbers , '44'); % all benchtop serial prefix.
            SerialNumbers = SerialNumbers(~logical(TempArray));
            app.MotorPool = {};
            d = uiprogressdlg(app.UIFigure,"Title",'Please wait','Message',"Initializing connection to motor controllers");
            for j = 1:length(SerialNumbers)
                % open motors
                % Put in temp "pool of motors"
                d.Value = j/length(SerialNumbers);
                try
                    app.MotorPool{end+1} = tlDCMotor();
                    app.MotorPool{end}.Open(SerialNumbers{j}); % Open tlDCMotor Motor
                    app.MotorPool{end}.Initialize();
                    app.MotorPool{end}.GetSetting;
                    app.UpdateLog(append('Communication Initiation with Thorlabs ', num2str(SerialNumbers{j}) ,' Succeeded'),'green');
                catch err
                    app.UpdateLog(append('Communication Initiation with Thorlabs ', num2str(SerialNumbers{j}) ,' Failed, ',err.message),'red');
                end
            end
            close(d);


            if ~isempty(SerialNumbers)
                % Initial population of variables
                app.MotorAddressDropDown.Items = SerialNumbers;
                app.MotorAddressDropDown.ItemsData = app.MotorPool;
            end
            TempObj.Close;

            app.ConnectStageButton.Enable = 'off';
            app.ConnectStageButton.BackgroundColor = [0 1 0]; % green
            app.ConnectStageButton.Text = 'Connected';

            app.MotorAddressDropDown.Enable = 'on';
            app.LockStageButton.Enable = 'on';


        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            stpsize = app.JogsizeEditField.Value;
            app.Stage.MoveJog(stpsize);
            app.UpdateStagePosition();
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            stpsize = app.JogsizeEditField.Value;
            app.Stage.MoveJog(-stpsize);
            app.UpdateStagePosition();
        end

        % Value changed function: MovetoPositionEditField
        function MovetoPositionEditFieldValueChanged(app, event)

        end

        % Button pushed function: MovetoabsoluteButton
        function MovetoabsoluteButtonPushed(app, event)
            pos = app.MovetoPositionEditField.Value;
            MSG=app.Stage.MoveToAbsPosition(pos);
            app.UpdateLog(MSG.txt,MSG.color);
            app.UpdateStagePosition();


        end

        % Button pushed function: FindRayleighLengthButton
        function FindRayleighLengthButtonPushed(app, event)
            app.UpdateLog('Start finding the Rayleigh Length');
            d = uiprogressdlg(app.UIFigure,"Title",'Please wait','Message',"Finding Rayleigh Length");
            
            w_r = app.W0EditField.Value * sqrt(2);

            % set jog size
            app.JogsizeEditField.Value = app.SteplengthEditField.Value;

            % move to the waist location
            app.MovetoPositionEditField.Value = app.Z0EditField.Value;
            app.MovetoabsoluteButtonPushed;
            current_width = w0;

            while current_width < w_r

                % jog up and calculate waist
                app.UpButtonPushed;
                D4s = app.cam.get_D4sigma(app.cam.getFrame());
                current_width = D4s.D4sigmaXY;

            end

            % DONE
            close(d);
            app.UpdateLog('DONE finding the Rayleigh Length');
            app.ZrEditField.Value = abs(app.PositionEditField.Value);

            app.FindRayleighLengthButton.Enable = 'off';
            app.FindRayleighLengthButton.Text = 'Done';
            app.FindRayleighLengthButton.BackgroundColor = [0 1 0]; % green
            app.CalculateM2Button.Enable = 'on';

        end

        % Value changed function: ZoomEditField
        function ZoomEditFieldValueChanged(app, event)
            
            
        end

        % Button pushed function: ZoomButton
        function ZoomButtonPushed(app, event)
            zoom = app.ZoomEditField.Value;

            if ~isempty(app.cam.device)
                tmpRes = get(app.cam.device, 'VideoResolution');
                MaxWidth = tmpRes(1);
                MaxHeight = tmpRes(2);
                Left = (MaxWidth - zoom)/2;
                Top = (MaxHeight - zoom)/2;
                stop(app.cam.device)
                app.cam.device.ROIPosition=[Left, Top, zoom, zoom];
                start(app.cam.device)
                
                if ishandle(app.cam_live_view)
                    xlim(gca, [1 zoom]);
                    ylim(gca, [1 zoom]);
                end

                app.UpdateLog('Zoom changed');
            end
        end

        % Button pushed function: ExposuretimeButton
        function ExposuretimeButtonPushed(app, event)
            value = app.ExposureEditField.Value;
            if ~isempty(app.cam)
                app.cam.src.ExposureTime = value;
                app.UpdateLog('Exposure Time changed');
            end
        end

        % Button pushed function: CalculateM2Button
        function CalculateM2ButtonPushed(app, event)
           
            % claculate the z position we want to measure based on Z0 and Zr
            % MAKE SURE that the stage move is DOUBLED
            % for each position: (1) do auto exposue (2) take 5 images 
            % (3) take the mean frame (4) calculate D4s
            % create a fit to the the in the form of: W = A + B*z + C*z^2
            % plot D4s vs Z_pos and the fit

            d = uiprogressdlg(app.UIFigure,"Title",'Please wait','Message',"Calculating M2");

            z0 = app.Z0EditField.Value;
            zr = app.ZrEditField.Value;
            d_zr = zr - z0;
            
            % building the location where to measure
            z_pos = [0.1 0.2 0.3 5 5.5 6];
            z_pos = [-flip(z_pos) 0 z_pos];
            z_pos = z0 + d_zr.* z_pos;

            
            for i = 1:length(z_pos)

                app.AutoExposureButtonPushed();

                % move stage
                app.Stage.MoveToAbsPosition(z_pos(i));

                % take 5 frames and mean them
                for j = 1:5
                    frame_tmp(:,:,j) = app.cam.getFrame();
                    pause(0.1); % TODO: check if needed
                end
                frame = mean(frame_tmp, 3);

                % get D4s and store in array
                D4s(i) = app.cam.get_D4sigma(frame);

            end

            % Start anlysis

            % normalize around 0
            x_axsis = ( z_pos - z0 ) / 2; % 1mm of the stage is twice the size for the light
            y_axsis = D4s(:).D4sigmaXY;

            [A, B, C, x_new, y_new] = app.quadraticFit(x_axsis, y_axsis);


            close(d)
        end

        % Button pushed function: AutoExposureButton
        function AutoExposureButtonPushed(app, event)
           
            if ~isempty(app.cam)
                ExpValue = app.SetAutoExposure();
                % ExpValue = app.SetAutoExposure2();

                app.UpdateLog('Auto Expoure it set');
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.ThorlabMotorPanel = uipanel(app.ConfigurationTab);
            app.ThorlabMotorPanel.Title = 'Thorlab Motor ';
            app.LockStageButton = uibutton(app.ThorlabMotorPanel, 'push');
            app.LockStageButton.ButtonPushedFcn = createCallbackFcn(app, @LockStageButtonPushed, true);
            app.LockStageButton.Enable = 'off';
            app.LockStageButton.Text = 'Lock';
            app.CloseStageButton = uibutton(app.ThorlabMotorPanel, 'push');
            app.CloseStageButton.ButtonPushedFcn = createCallbackFcn(app, @CloseStageButtonPushed, true);
            app.CloseStageButton.Enable = 'off';
            app.CloseStageButton.Text = 'Close';
            app.ConnectStageButton = uibutton(app.ThorlabMotorPanel, 'push');
            app.ConnectStageButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectStageButtonPushed, true);
            app.ConnectStageButton.Text = 'Connect';
            app.MotorAddressDropDownLabel = uilabel(app.ThorlabMotorPanel);
            app.MotorAddressDropDownLabel.Text = 'Motor Address';
            app.MotorAddressDropDown = uidropdown(app.ThorlabMotorPanel);
            app.MotorAddressDropDown.Items = {''};
            app.MotorAddressDropDown.Enable = 'off';
            app.MotorAddressDropDown.Value = '';
            app.CameraPanel = uipanel(app.ConfigurationTab);
            app.CameraPanel.Title = 'Camera';
            app.ConnectCameraButton = uibutton(app.CameraPanel, 'push');
            app.ConnectCameraButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectCameraButtonPushed, true);
            app.ConnectCameraButton.Text = 'Connect';
            app.closeCameraButton = uibutton(app.CameraPanel, 'push');
            app.closeCameraButton.ButtonPushedFcn = createCallbackFcn(app, @closeCameraButtonPushed, true);
            app.closeCameraButton.Enable = 'off';
            app.closeCameraButton.Text = 'close';
            app.CameraTab = uitab(app.TabGroup);
            app.CameraTab.Title = 'Camera';
            app.CameraSettingPanel = uipanel(app.CameraTab);
            app.CameraSettingPanel.Enable = 'off';
            app.CameraSettingPanel.Title = 'Camera Setting';
            app.ZoomEditField = uieditfield(app.CameraSettingPanel, 'numeric');
            app.ZoomEditField.ValueChangedFcn = createCallbackFcn(app, @ZoomEditFieldValueChanged, true);
            app.msEditFieldLabel = uilabel(app.CameraSettingPanel);
            app.msEditFieldLabel.Text = ' [ms]';
            app.ExposureEditField = uieditfield(app.CameraSettingPanel, 'numeric');
            app.ExposureEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureEditFieldValueChanged, true);
            app.ColormapDropDown = uidropdown(app.CameraSettingPanel);
            app.ColormapDropDown.Items = {'jet', 'gray', 'hsv', 'hot', 'parula'};
            app.ColormapDropDown.Editable = 'on';
            app.ColormapDropDown.Value = 'jet';
            app.ColormapLabel = uilabel(app.CameraSettingPanel);
            app.ColormapLabel.Text = 'Colormap';
            app.D4sCheckBox = uicheckbox(app.CameraSettingPanel);
            app.D4sCheckBox.Text = 'D4s';
            app.LiveButton = uibutton(app.CameraSettingPanel, 'push');
            app.LiveButton.ButtonPushedFcn = createCallbackFcn(app, @LiveButtonPushed, true);
            app.LiveButton.Text = 'Live';
            app.ZoomButton = uibutton(app.CameraSettingPanel, 'push');
            app.ZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomButtonPushed, true);
            app.ZoomButton.Text = 'Zoom';
            app.ExposuretimeButton = uibutton(app.CameraSettingPanel, 'push');
            app.ExposuretimeButton.ButtonPushedFcn = createCallbackFcn(app, @ExposuretimeButtonPushed, true);
            app.ExposuretimeButton.Text = 'Exposure time';
            app.AutoExposureButton = uibutton(app.CameraSettingPanel, 'push');
            app.AutoExposureButton.ButtonPushedFcn = createCallbackFcn(app, @AutoExposureButtonPushed, true);
            app.AutoExposureButton.Text = 'Auto';
            app.M2Tab = uitab(app.TabGroup);
            app.M2Tab.Title = 'M2';
            app.ThorlabStagePanel = uipanel(app.M2Tab);
            app.ThorlabStagePanel.Enable = 'off';
            app.ThorlabStagePanel.Title = 'Thorlab Stage';
            app.UpButton = uibutton(app.ThorlabStagePanel, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.ThorlabStagePanel, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.JogsizeEditFieldLabel = uilabel(app.ThorlabStagePanel);
            app.JogsizeEditFieldLabel.Text = 'Jog size';
            app.JogsizeEditField = uieditfield(app.ThorlabStagePanel, 'numeric');
            app.JogsizeEditField.Value = 1;
            app.MovetoPositionEditField = uieditfield(app.ThorlabStagePanel, 'numeric');
            app.MovetoPositionEditField.ValueChangedFcn = createCallbackFcn(app, @MovetoPositionEditFieldValueChanged, true);
            app.MovetoabsoluteButton = uibutton(app.ThorlabStagePanel, 'push');
            app.MovetoabsoluteButton.ButtonPushedFcn = createCallbackFcn(app, @MovetoabsoluteButtonPushed, true);
            app.MovetoabsoluteButton.Text = 'Move to absolute';
            app.PositionEditField_2Label = uilabel(app.ThorlabStagePanel);
            app.PositionEditField_2Label.Text = 'Position';
            app.PositionEditField = uieditfield(app.ThorlabStagePanel, 'text');
            app.PositionEditField.Editable = 'off';
            app.InitialStepmmEditFieldLabel = uilabel(app.M2Tab);
            app.InitialStepmmEditFieldLabel.Text = 'Initial Step [mm]';
            app.InitialStepEditField = uieditfield(app.M2Tab, 'numeric');
            app.InitialStepEditField.Value = 1;
            app.FindWaistLocationButton = uibutton(app.M2Tab, 'push');
            app.FindWaistLocationButton.ButtonPushedFcn = createCallbackFcn(app, @FindWaistLocationButtonPushed, true);
            app.FindWaistLocationButton.Enable = 'off';
            app.FindWaistLocationButton.Text = 'Find Waist Location';
            app.W0EditFieldLabel = uilabel(app.M2Tab);
            app.W0EditFieldLabel.Text = 'W0';
            app.W0EditField = uieditfield(app.M2Tab, 'numeric');
            app.W0EditField.Editable = 'off';
            app.Z0EditFieldLabel = uilabel(app.M2Tab);
            app.Z0EditFieldLabel.Text = 'Z0';
            app.Z0EditField = uieditfield(app.M2Tab, 'numeric');
            app.Z0EditField.Editable = 'off';
            app.MinimalStepmmEditFieldLabel = uilabel(app.M2Tab);
            app.MinimalStepmmEditFieldLabel.Text = 'Minimal Step [mm]';
            app.MinimalStepEditField = uieditfield(app.M2Tab, 'numeric');
            app.MinimalStepEditField.Value = 0.1;
            app.SteplengthEditFieldLabel = uilabel(app.M2Tab);
            app.SteplengthEditFieldLabel.Text = 'Step length';
            app.SteplengthEditField = uieditfield(app.M2Tab, 'numeric');
            app.SteplengthEditField.Value = 0.1;
            app.FindRayleighLengthButton = uibutton(app.M2Tab, 'push');
            app.FindRayleighLengthButton.ButtonPushedFcn = createCallbackFcn(app, @FindRayleighLengthButtonPushed, true);
            app.FindRayleighLengthButton.Enable = 'off';
            app.FindRayleighLengthButton.Text = 'Find Rayleigh Length';
            app.ZrEditFieldLabel = uilabel(app.M2Tab);
            app.ZrEditFieldLabel.Text = 'Zr';
            app.ZrEditField = uieditfield(app.M2Tab, 'numeric');
            app.ZrEditField.Editable = 'off';
            app.CalculateM2Button = uibutton(app.M2Tab, 'push');
            app.CalculateM2Button.ButtonPushedFcn = createCallbackFcn(app, @CalculateM2ButtonPushed, true);
            app.CalculateM2Button.Enable = 'off';
            app.CalculateM2Button.Text = 'Calculate M2';
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogEdit = uitextarea(app.UIFigure);
            app.LogEdit.Tag = 'LogBox';
            app.LogEdit.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CameraApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end